classdef page3POC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ClockButton         matlab.ui.control.StateButton
        counterButton       matlab.ui.control.StateButton
        Score               matlab.ui.control.Button
        LaunchButton        matlab.ui.control.StateButton
        ScoreLabel          matlab.ui.control.Label
        TakeapictureButton  matlab.ui.control.StateButton
        UpButton            matlab.ui.control.StateButton
        StepperMotorLabel   matlab.ui.control.Label
        DownButton          matlab.ui.control.StateButton
        CloseButton         matlab.ui.control.StateButton
        InstructionsButton  matlab.ui.control.StateButton
        StarTittle          matlab.ui.control.Image
        background          matlab.ui.control.Image
        Image               matlab.ui.control.Image
    end

        
        properties (Access = private)
            a1;
            score = 0;
            xDist = 512;
            yDist = 330;
        end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.a1; % Clear out Arduino
            app.Score.Text = '0';
            app.a1 = arduino ('COM3', 'uno'); % Connect the left Arduino


            Eb = 311;
            Fsh = 370;
            G = 392;
            Ab = 415;
            A = 440;
            Bb = 466;
            B = 494;
            c5 = 523;
            c5Sharp = 554;
            d5 = 587;
            e5b = 622;
            e5 = 659;
            f5 = 698;
            f5sh = 740;
            g5 = 784;
            Rest = 0;

            % Durations of musical notes, in seconds.
            Whole = 2.1;
            Half = Whole / 2;
            Quarter = Whole / 4;
            Eighth = Whole/8;
            EighthDot = (Whole/8) + (Whole/16);
            Sixteenth = Whole/16;

            % The song to play.  Column 1 is the note, column 2 the duration.
            Song1 = [
                G, Quarter;...
                G, Quarter;...
                G, Quarter;...
                Eb, EighthDot;...
                Bb, Sixteenth;...
                G, Quarter;...
                Eb, EighthDot;...
                Bb, Sixteenth;...
                G, Half;...

                d5, Quarter;...
                d5, Quarter;...
                d5, Quarter;...
                e5b, EighthDot;...
                Bb, Sixteenth;...
                Fsh, Quarter;...
                Eb, EighthDot;...
                Bb, Sixteenth;...
                G, Half;...

                g5, Quarter;...
                G, EighthDot;...
                G, Sixteenth;...
                g5, Quarter;...
                f5sh, EighthDot;...
                f5, Sixteenth;...
                e5, Sixteenth;...
                Eb, Sixteenth;...
                e5, Eighth;...
                Rest, Eighth;...

                Ab, Eighth;...
                c5Sharp, Quarter;...
                c5, EighthDot;...
                B, Sixteenth;...
                Bb, Sixteenth;...
                A, Sixteenth;...
                Bb, Eighth;...
                Rest, Eighth;...

                Eb, Eighth;...
                Fsh, Quarter;...
                Eb, EighthDot;...
                Fsh, Sixteenth;...
                Bb, Quarter;...
                G, EighthDot;...
                Bb, Sixteenth;...
                d5, Half;...

                g5, Quarter;...
                G, EighthDot;...
                G, Sixteenth;...
                g5, Quarter;...
                f5sh, EighthDot;...
                f5, Sixteenth;...
                e5, Sixteenth;...
                Eb, Sixteenth;...
                e5, Eighth;...
                Rest, Eighth;...

                Ab, Eighth;...
                c5Sharp, Quarter;...
                c5, EighthDot;...
                B, Sixteenth;...
                Bb, Sixteenth;...
                A, Sixteenth;...
                Bb, Eighth;...
                Rest, Eighth;...

                Eb, Eighth;...
                Fsh, Quarter;...
                Eb, EighthDot;...
                Bb, Sixteenth;...
                G, Quarter;...
                Eb, EighthDot;...
                Bb, Sixteenth;...
                G, Half;
                ];

            while true
                for idx = 1 : length(Song1) % number of rows
                    playTone(app.a1, 'D3', Song1(idx, 1), Song1(idx, 2));
                    %Wait until the note is over before starting the next one.
                    pause(Song1(idx, 2));
                end

            end
        end

        % Value changed function: InstructionsButton
        function InstructionsButtonValueChanged(app, event)
                value = app.InstructionsButton.Value;
                
                if(value == 1)
                    page2POC
                end
        end

        % Value changed function: LaunchButton
        function LaunchButtonValueChanged(app, event)
            value = app.LaunchButton.Value;

            s1 = servo(app.a1, 'D5', 'MinPulseDuration', 700*10^-6, ...
                'MaxPulseDuration', 2300*10^-6);

            s2 = servo(app.a1, 'D6', 'MinPulseDuration', 700*10^-6, ...
                'MaxPulseDuration', 2300*10^-6);

            writePosition(s1, 0);
            writePosition(s2, 0);
            pause(1);

            if(value == 1)
                writePosition(s1, 1);
                writePosition(s2, 1);          

                pause(1);
                app.score = app.score + 1;
                app.LaunchButton.BackgroundColor = [1.0, 1.0, 1.0];
                app.LaunchButton.Value = 0;
                app.Score.Text = num2str(app.score);
    
                clear s;                        %Clears the serial port
                s = serialport('COM4', 9600);  % Establishes a COM port to communicate
                pause (2);                      %Pause
                % Develop a variable to rotate
                write(s, int2str (app.xDist * 5), 'string');
                write(s, " ", 'string');
                stepper = 2;
                write(s, int2str(stepper), 'string');

                clear s;                        
                s = serialport('COM4', 9600);
                clear stepper;
                pause(2);
                write(s, " ", 'string');
                write(s, int2str (app.yDist), 'string');
                write(s, " ", 'string');
                stepper = 1;
                write(s, int2str(stepper), 'string');

            end

                
                writePosition(s1, 0);
                writePosition(s2, 0);
                pause(1);
            
        end

        % Value changed function: UpButton
        function UpButtonValueChanged(app, event)
            value = app.UpButton.Value; %counter negative
             if(value == 1)
                clear s;                        %Clears the serial port
                s = serialport('COM4', 9600);  % Establishes a COM port to communicate
                pause (2);                      %Pause
                % Develop a variable to rotate
                write(s, int2str (app.yDist * -1), 'string');
                write(s, " ", 'string');
                stepper = 1;
                write(s, int2str(stepper), 'string');
                app.UpButton.Value = 0;
            end
        end

        % Value changed function: DownButton
        function DownButtonValueChanged(app, event)
            value = app.DownButton.Value; %clock positive
            if(value == 1)
                clear s;                     %Clears the serial port
                s = serialport('COM4', 9600);  % Establishes a COM port to communicate
                pause (2);                      %Pause
                % Develop a variable to rotate
                write(s, int2str (app.yDist), 'string');
                write(s, " ", 'string');
                stepper = 1;
                write(s, int2str(stepper), 'string');
                app.DownButton.Value = 0;
                
            end
        end

        % Value changed function: ClockButton
        function ClockButtonValueChanged(app, event)
            value = app.ClockButton.Value;
            if(value == 1)
                clear s;                        %Clears the serial port
                s = serialport('COM4', 9600);  % Establishes a COM port to communicate
                pause (2);                      %Pause
                % Develop a variable to rotate
                write(s, int2str (app.xDist * 5), 'string');
                write(s, " ", 'string');
                stepper = 2;
                write(s, int2str(stepper), 'string');
                app.ClockButton.Value = 0;
           end
        end

        % Value changed function: counterButton
        function counterButtonValueChanged(app, event)
            value = app.counterButton.Value;
             if(value == 1)
                clear s;                        %Clears the serial port
                s = serialport('COM4', 9600);  % Establishes a COM port to communicate
                pause (2);                      %Pause
                write(s, int2str (app.xDist * -5), 'string');
                write(s, " ", 'string');
                stepper = 2;
                write(s, int2str(stepper), 'string');
                app.counterButton.Value = 0;
            end
            
        end

        % Value changed function: CloseButton
        function CloseButtonValueChanged(app, event)
            value = app.CloseButton.Value;
            if(value == 1)
                delete(app)
            end
        end

        % Value changed function: TakeapictureButton
        function TakeapictureButtonValueChanged(app, event)
            value = app.TakeapictureButton.Value;

            if(value == 1)
                app.xDist = 512;
                app.yDist = 330;

                m = mobiledev;
                % Flip to back Camera
                cam = camera(m,'back');
                % Focus camera
                cam.Resolution='1280x720';
%               cam.Flash = 'on';
                cam.Autofocus = 'on';
                % Take Photo
                img = snapshot(cam,'immediate');
                imwrite(img,'latestPicture.jpg');
                image = imread('latestPicture.jpg');
                % Show photo
                %imtool(image);
                % Color Values
                g_channel=image(:,:,2);
                r_channel=image(:,:,1);
                b_channel=image(:,:,3);
                % Color ratios
                gr_ratio=double(g_channel)./double(r_channel); % Green red ratio
                gb_ratio=double(g_channel)./double(b_channel); % Green blue ratio
                gr_ratio(isnan(gr_ratio)) = 0;
                gb_ratio(isnan(gb_ratio)) = 0;
                % Pull out green values
                g_bin = (gb_ratio>= 1.45 & gr_ratio>= 1.40 & b_channel <= 130);
                % Turns Photo black and white
                black_and_white=bwareaopen(g_bin,80);
                % Fills in holes
                black_and_white=imfill(g_bin,"holes");
                black_and_white = bwareaopen(g_bin,80);
                % Shows photo
                %imshow(black_and_white)
                % Finds the center of the markers
                dotPlacements = regionprops('table',black_and_white,'Centroid');
                UsefulTable = dotPlacements{:,:};
                totalX = (UsefulTable(1,1) + UsefulTable(2,1) + UsefulTable(3,1) + UsefulTable(4,1));
                totalY = (UsefulTable(1,2) + UsefulTable(2,2) + UsefulTable(3,2) + UsefulTable(4,2));
                numVal = 4;
                % Averages out the x and y placements of markers
                AvgX = totalX/numVal;
                AvgY = totalY/numVal;
                % Finds the placement of the markers to the left and right
                topXdist = (UsefulTable(1,1) + UsefulTable(2,1))/2;
                bottomXdist = (UsefulTable(3,1) + UsefulTable(4,1))/2;
                % Distance from target
                %Finds the distance between the markers
                distXAvg = bottomXdist - topXdist;
                % Finds the distance from target in X
                multipliedX = distXAvg/(6941);
                Xroot = nthroot(multipliedX, -1);
                % Finds the placement of the markers to the top and bottom
                rightYdist = (UsefulTable(1,2) + UsefulTable(3,2))/2;
                leftYdist = (UsefulTable(4,2) + UsefulTable(4,2))/2;
                % Finds the distance between the markers
                distYavg = abs(leftYdist - rightYdist);
                % Finds the distance from target in X
                multipliedY = distYavg/(3496.6);
                Yroot = (nthroot(multipliedY, -1.004));
                %Averages out the two distances
                %targetDist = (Yroot + Xroot)/2;
                %fprintf('Distance from Target:%.2f inches \n', Xroot);

                % Finding the Horizontal Angle
                XdirectionTarget = (AvgX - 640);
                % Convert from pixles to inches
                pixleRatio = XdirectionTarget/distXAvg;
                PixleConversion = pixleRatio * 8;
                % Find horizontal angle of the target
                HorizontalAngle = atand(PixleConversion/ Xroot);
                xAngle = 90 + HorizontalAngle;
                xAngle = xAngle * 5.7;
                app.xDist = (app.xDist + (10 * 5)) - xAngle;

                clear s;                        %Clears the serial port
                s = serialport('COM4', 9600);  % Establishes a COM port to communicate
                pause (2);                      %Pause
                % Develop a variable to rotate
                write(s, int2str (app.xDist * -5), 'string');
                write(s, " ", 'string');
                stepper = 2;
                write(s, int2str(stepper), 'string');

                %fprintf('Horizontal Angle:%.f degrees \n',  xDist);
                % Finding the Vertical Angle
                % Pull out Red values
                r_bin = (gr_ratio >= .60 & b_channel >= 170.0 & gr_ratio >= 1.7 & r_channel <= 50.0);
                % Turns Photo black and white
                b_and_w=bwareaopen(r_bin,50);
                % Fills in holes
                b_and_w = imfill(r_bin,"holes");
                bottomLine = regionprops('table', b_and_w, 'Centroid');
                %imshow(b_and_w)
                % imtool(b_and_w)
                b_and_w = bwareaopen(r_bin,80);
                Table = bottomLine{:,:};
               % zero = (Table(2,2) + Table(1,2))/2;
                %YdirectionTarget = abs(zero - AvgY);
                % Converts from pixles into inches
%                 YpixleRatio = YdirectionTarget/distYavg;
%                 YPixleConversion = YpixleRatio * 8;
%                 APixleConversion = YPixleConversion + 4;
%                 % Find the Vertical Angle
%                 yAngle = atand(APixleConversion/ Xroot);
%                 yAngle = yAngle * 5.7;
                app.yDist = 330;

                clear stepper;
                
                pause(2);
                write(s, " ", 'string');
                write(s, int2str (app.yDist * -1), 'string');
                write(s, " ", 'string');
                stepper = 1;
                write(s, int2str(stepper), 'string');

                              
                app.LaunchButton.BackgroundColor = [1.0, 0, 0];
                app.TakeapictureButton.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = 'waho.jpg';
            app.StarTittle = uiimage(app.UIFigure);
            app.StarTittle.ImageSource = 'StarTittle.png';
            app.InstructionsButton = uibutton(app.UIFigure, 'state');
            app.InstructionsButton.ValueChangedFcn = createCallbackFcn(app, @InstructionsButtonValueChanged, true);
            app.InstructionsButton.Text = 'Instructions';
            app.CloseButton = uibutton(app.UIFigure, 'state');
            app.CloseButton.ValueChangedFcn = createCallbackFcn(app, @CloseButtonValueChanged, true);
            app.CloseButton.Text = 'Close';
            app.DownButton = uibutton(app.UIFigure, 'state');
            app.DownButton.ValueChangedFcn = createCallbackFcn(app, @DownButtonValueChanged, true);
            app.DownButton.Text = 'Down';
            app.StepperMotorLabel = uilabel(app.UIFigure);
            app.StepperMotorLabel.Text = 'Stepper Motor';
            app.UpButton = uibutton(app.UIFigure, 'state');
            app.UpButton.ValueChangedFcn = createCallbackFcn(app, @UpButtonValueChanged, true);
            app.UpButton.Text = 'Up';
            app.TakeapictureButton = uibutton(app.UIFigure, 'state');
            app.TakeapictureButton.ValueChangedFcn = createCallbackFcn(app, @TakeapictureButtonValueChanged, true);
            app.TakeapictureButton.Text = 'Take a picture';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score: ';
            app.LaunchButton = uibutton(app.UIFigure, 'state');
            app.LaunchButton.ValueChangedFcn = createCallbackFcn(app, @LaunchButtonValueChanged, true);
            app.LaunchButton.Text = 'Launch';
            app.Score = uibutton(app.UIFigure, 'push');
            app.Score.Text = '';
            app.counterButton = uibutton(app.UIFigure, 'state');
            app.counterButton.ValueChangedFcn = createCallbackFcn(app, @counterButtonValueChanged, true);
            app.counterButton.Text = 'counter';
            app.ClockButton = uibutton(app.UIFigure, 'state');
            app.ClockButton.ValueChangedFcn = createCallbackFcn(app, @ClockButtonValueChanged, true);
            app.ClockButton.Text = 'Clock';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page3POC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end